classdef RocketTileUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaterialpropertiesPanel         matlab.ui.container.Panel
        ThermalConductivityWmKEditField  matlab.ui.control.NumericEditField
        ThermalConductivityWmKLabel     matlab.ui.control.Label
        DensitykgmEditField             matlab.ui.control.NumericEditField
        DensitykgmLabel                 matlab.ui.control.Label
        SpecificHeatCapacityJkgKEditField  matlab.ui.control.NumericEditField
        SpecificHeatCapacityJkgKLabel   matlab.ui.control.Label
        ChangeMaterialPropertiesButton  matlab.ui.control.StateButton
        CheckBox                        matlab.ui.control.CheckBox
        Label_5                         matlab.ui.control.Label
        ErrorLabel                      matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        MaximuminsidetemperatureLabel   matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        FButton_2                       matlab.ui.control.ToggleButton
        KButton                         matlab.ui.control.ToggleButton
        CButton                         matlab.ui.control.ToggleButton
        DropDown                        matlab.ui.control.DropDown
        Label_2                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Button_8                        matlab.ui.control.StateButton
        Button_7                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_4                        matlab.ui.control.StateButton
        Button_3                        matlab.ui.control.StateButton
        Button_2                        matlab.ui.control.StateButton
        Button                          matlab.ui.control.StateButton
        Image                           matlab.ui.control.Image
        StepsizeLabel                   matlab.ui.control.Label
        NumberofspacialstepsLabel       matlab.ui.control.Label
        TimestepLabel                   matlab.ui.control.Label
        NumericalMethodDropDown         matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        BoundaryConditonDropDown        matlab.ui.control.DropDown
        BoundaryConditonLabel           matlab.ui.control.Label
        TotaltimeEditField              matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        NumberofTimestepsSpinner        matlab.ui.control.Spinner
        NumberofTimestepsLabel          matlab.ui.control.Label
        TotaltimeLabel                  matlab.ui.control.Label
        TimestepEditField               matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        NumberofspacialstepsSpinner     matlab.ui.control.Spinner
        StepsizeEditField               matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        Label_3                         matlab.ui.control.Label
        OptimalthicknessLabel           matlab.ui.control.Label
        TilethicknessLabel              matlab.ui.control.Label
        TilethicknessEditField          matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        Button_10                       matlab.ui.control.RadioButton
        Button_9                        matlab.ui.control.RadioButton
        TabGroup                        matlab.ui.container.TabGroup
        TemperatureProfileTab           matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        AnimationTab                    matlab.ui.container.Tab
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x = []; % x coordinate vector
        t = []; % time vector
        u = []; % results matrix
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Automatically ananlyse all temperature data on startup
            temps = {'temp468' 'temp480' 'temp502' 'temp590' 'temp597' 'temp711' 'temp730' 'temp850'};
            for i = temps
                plottempAutoo(char(i));
            end
        end

        % Callback function: ButtonGroup, RunButton
        function TileTemp(app, event)
            % Store variables
            tmax = app.TotaltimeEditField.Value;
            nt = app.NumberofTimestepsSpinner.Value;
            xmax = app.TilethicknessEditField.Value;
            nx = app.NumberofspacialstepsSpinner.Value;
            method = app.NumericalMethodDropDown.Value;
            prop = {app.BoundaryConditonDropDown.Value app.ThermalConductivityWmKEditField.Value ...
                app.DensitykgmEditField.Value app.SpecificHeatCapacityJkgKEditField.Value};
            unit = 'K';
            lim = [0 1400];

            load(string(app.DropDown.Value),'tempData','timeData')

            if app.ButtonGroup_2.SelectedObject == app.Button_10
                % Calculate tile thickness if user has chosen to
                xmax = tileThickness(tmax, nt, nx, method, prop, timeData, tempData);
                if ~isnan(xmax)
                    app.mLabel_2.FontColor = 'black';
                    app.mLabel_2.Text = [sprintf('%3.3f', xmax) ' m'];
                else
                    app.mLabel_2.FontColor = 'red';
                end
            end

            [app.x, app.t, app.u] = calctemp(tmax, nt, xmax, nx, method, prop, timeData, tempData);

            % Temperature units
            switch app.ButtonGroup.SelectedObject
                case app.CButton
                    app.u = app.u - 273.25;
                    unit = '°C';
                    lim = [-273 1100];
                case app.FButton_2
                    app.u = (app.u - 273.25) * 9/5 + 32;
                    unit = '°F';
                    lim = [-500 2000];
            end

            if app.CheckBox.Value
                % Calculate error
                Err = Analyser(tmax, nt, xmax, nx, method, prop, unit, timeData, tempData);
                app.Label_5.Text = [sprintf('%3.2g', Err) ' %'];
            end

            % Maximum temperature
            maxTempin = max(app.u(:,nx));
            app.KLabel.Text = [sprintf('%3.1f', maxTempin) ' ' unit];

            % Plot the data in the axes
            plot(app.UIAxes2,app.t,[app.u(:,1) app.u(:,nx)])
            ylabel(app.UIAxes2, ['Temperature ('  unit  ')'])
            grid(app.UIAxes2, "on")
            legend(app.UIAxes2, 'Outside boundary', 'Inner boundary')

            % contour plot
            surf(app.UIAxes,app.x,app.t,app.u)
            % comment out the next line to change the surface appearance
            shading(app.UIAxes,"interp") 
            
            % Rotate the view
            view(app.UIAxes,50,30)
            
            % label the title and axes
            xlabel(app.UIAxes, 'Axial distance (m)')
            ylabel(app.UIAxes, 'Time (s)')
            zlabel(app.UIAxes, ['Temperature ('  unit  ')'])
            title(app.UIAxes, method)
            grid(app.UIAxes, "on")

            % Animation
            h = plot(app.UIAxes3, app.x, app.u(1,:));

            xlabel(app.UIAxes3, 'Axial distance (m)')
            ylabel(app.UIAxes3, ['Temperature ('  unit  ')'])
            grid(app.UIAxes3, "on")
            app.UIAxes3.XMinorGrid = "on";
            app.UIAxes3.YMinorGrid = "on";
            ylim(app.UIAxes3, lim)

            % now loop through time
            for n = linspace(1,nt-1,150)
                % Break if figure has been closed.
                if ~ishandle(h)
                    break;
                end

                % Update graph with new values
                set(h,'YData', app.u(round(n+1),:));

                % and refresh it on screen
                drawnow

                % wait so animation is produced
                pause(0.01)
            end

            focus(app.UIFigure)
        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            app.NumericalMethodDropDown.Value = 'Crank-Nicolson';
            app.BoundaryConditonDropDown.Value = 'Neumann';
            app.TotaltimeEditField.Value = 4000;
            app.NumberofTimestepsSpinner.Value = 501;
            app.TimestepEditField.Value = 8;
            app.NumberofspacialstepsSpinner.Value = 21;
            app.StepsizeEditField.Value = 0.0025;
            app.TilethicknessEditField.Value = 0.05;
            app.mLabel_2.Text = '____ m';
            app.Label_5.Text = '_____ %';
            app.ThermalConductivityWmKEditField.Value = '0.0484';
            app.DensitykgmEditField.Value = '144';
            app.SpecificHeatCapacityJkgKEditField.Value = '628';
        end

        % Value changed function: Button, Button_2, Button_3, Button_4, 
        % ...and 5 other components
        function Deselect(app, event)
            % Change temperature data based on tile selected by the user
            Buttons = [app.Button app.Button_2 app.Button_3 app.Button_4 app.Button_5 ...
                app.Button_6 app.Button_7 app.Button_8];

            if any(event.Source == Buttons)
                tempFile = strcmp([Buttons.Tooltip],event.Source.Tooltip);
                app.DropDown.Value = Buttons(tempFile).Tooltip;
                focus(app.UIFigure)
            elseif event.Source == app.DropDown
                tempFile = strcmp([Buttons.Tooltip],app.DropDown.Value);
            elseif strcmp(event.Key, 'rightarrow')
                tempFile = mod(find([Buttons.Value], 1), 8) + 1;
                app.DropDown.Value = Buttons(tempFile).Tooltip;
            elseif strcmp(event.Key,'leftarrow')
                tempFile = mod(find([Buttons.Value], 1) - 2, 8) + 1;
                app.DropDown.Value = Buttons(tempFile).Tooltip;
            end

            set(Buttons, 'Background', [0.96 0.96 0.96])
            set(Buttons, 'Value', 0)

            Buttons(tempFile).BackgroundColor = [1 0 0];
            Buttons(tempFile).Value = 1;
            app.Image2.ImageSource = fullfile(string(Buttons(tempFile).Tooltip) + '.jpg');
        end

        % Callback function: BoundaryConditonDropDown, 
        % ...and 7 other components
        function KeyPress(app, event)
            % General function when user interacts with the interface.
            switch event.Source
                case app.BoundaryConditonDropDown
                    if strcmp(app.BoundaryConditonDropDown.Value, 'Dirichlet')
                        app.ButtonGroup_2.SelectedObject = app.Button_9;
                    end
                    Choice(app)
                case app.UIFigure
                    key = event.Key;
                    if strcmp(key,'return')
                        TileTemp(app)
                    elseif any(strcmp(key,{'rightarrow' 'leftarrow'}))
                        Deselect(app,event)
                    end
                case app.Image
                    app.UIFigure.focus
                case {app.NumberofTimestepsSpinner, app.TotaltimeEditField}
                    app.TimestepEditField.Value = app.TotaltimeEditField.Value/(app.NumberofTimestepsSpinner.Value - 1);
                case {app.NumberofspacialstepsSpinner, app.TilethicknessEditField}
                    app.StepsizeEditField.Value = app.TilethicknessEditField.Value/(app.NumberofspacialstepsSpinner.Value - 1);
                case app.ChangeMaterialPropertiesButton
                    if app.ChangeMaterialPropertiesButton.Value
                        app.MaterialpropertiesPanel.Visible = "on";
                    else
                        app.MaterialpropertiesPanel.Visible = "off";
                    end
            end
        end

        % Selection changed function: ButtonGroup_2
        function Choice(app, event)
            % Changes appearance of some based on user selection
            selectedButton = app.ButtonGroup_2.SelectedObject;
            if selectedButton == app.Button_9
                app.Button_10.Value = 0;
                app.TilethicknessEditField.Enable = "on";
                set([app.OptimalthicknessLabel app.mLabel_2],'Enable',"off");
                app.mLabel_2.Text = '____ m';
            elseif selectedButton == app.Button_10
                app.BoundaryConditonDropDown.Value = 'Neumann';
                app.Button_9.Value = 0;
                app.TilethicknessEditField.Enable = "off";
                set([app.OptimalthicknessLabel app.mLabel_2],'Enable',"on");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @KeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D plot')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TemperatureProfileTab = uitab(app.TabGroup);
            app.TemperatureProfileTab.Title = 'Temperature Profile';
            app.UIAxes2 = uiaxes(app.TemperatureProfileTab);
            title(app.UIAxes2, 'Temperature Profile')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Temperature')
            app.AnimationTab = uitab(app.TabGroup);
            app.AnimationTab.Title = 'Animation';
            app.UIAxes3 = uiaxes(app.AnimationTab);
            title(app.UIAxes3, 'Animation')
            xlabel(app.UIAxes3, 'Axial distance')
            ylabel(app.UIAxes3, 'Temperature')
            zlabel(app.UIAxes3, 'Z')
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @Choice, true);
            app.Button_9 = uiradiobutton(app.ButtonGroup_2);
            app.Button_9.Tooltip = {'Edit tile thicknes'};
            app.Button_9.Text = '';
            app.Button_10 = uiradiobutton(app.ButtonGroup_2);
            app.Button_10.Tooltip = {'Find optimal thickness'};
            app.Button_10.Text = '';
            app.Button_10.Value = true;
            app.mLabel_2 = uilabel(app.ButtonGroup_2);
            app.mLabel_2.Text = '____ m';
            app.TilethicknessEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.TilethicknessEditField.Limits = [0 Inf];
            app.TilethicknessEditField.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.TilethicknessEditField.Enable = 'off';
            app.TilethicknessEditField.Value = 0.05;
            app.TilethicknessLabel = uilabel(app.ButtonGroup_2);
            app.TilethicknessLabel.Text = 'Tile thickness:';
            app.OptimalthicknessLabel = uilabel(app.ButtonGroup_2);
            app.OptimalthicknessLabel.Text = 'Optimal thickness:';
            app.Label_3 = uilabel(app.ButtonGroup_2);
            app.Label_3.Tooltip = {'It is recommended to use Crank-Nicolson to calculate optimal thickness at it is the most accurate method.'};
            app.Label_3.Text = '🛈';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @TileTemp, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.StepsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizeEditField.Limits = [0 Inf];
            app.StepsizeEditField.Editable = 'off';
            app.StepsizeEditField.Value = 0.0025;
            app.NumberofspacialstepsSpinner = uispinner(app.UIFigure);
            app.NumberofspacialstepsSpinner.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.NumberofspacialstepsSpinner.Tooltip = {''};
            app.NumberofspacialstepsSpinner.Value = 21;
            app.sLabel_2 = uilabel(app.UIFigure);
            app.sLabel_2.Text = 's';
            app.TimestepEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimestepEditField.Limits = [0 Inf];
            app.TimestepEditField.Editable = 'off';
            app.TimestepEditField.Value = 8;
            app.TotaltimeLabel = uilabel(app.UIFigure);
            app.TotaltimeLabel.Text = 'Total time:';
            app.NumberofTimestepsLabel = uilabel(app.UIFigure);
            app.NumberofTimestepsLabel.Tooltip = {'Rightclick'};
            app.NumberofTimestepsLabel.Text = {'Number of'; 'Timesteps:'};
            app.NumberofTimestepsSpinner = uispinner(app.UIFigure);
            app.NumberofTimestepsSpinner.Step = 20;
            app.NumberofTimestepsSpinner.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.NumberofTimestepsSpinner.Tooltip = {''};
            app.NumberofTimestepsSpinner.Value = 501;
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.TotaltimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotaltimeEditField.Limits = [0 Inf];
            app.TotaltimeEditField.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.TotaltimeEditField.Value = 4000;
            app.BoundaryConditonLabel = uilabel(app.UIFigure);
            app.BoundaryConditonLabel.Text = 'Boundary Conditon';
            app.BoundaryConditonDropDown = uidropdown(app.UIFigure);
            app.BoundaryConditonDropDown.Items = {'Neumann', 'Dirichlet'};
            app.BoundaryConditonDropDown.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.BoundaryConditonDropDown.Value = 'Neumann';
            app.MethodLabel = uilabel(app.UIFigure);
            app.MethodLabel.Text = 'Numerical Method';
            app.NumericalMethodDropDown = uidropdown(app.UIFigure);
            app.NumericalMethodDropDown.Items = {'Forward differencing', 'DuFort-Frankel', 'Backward differencing', 'Crank-Nicolson'};
            app.NumericalMethodDropDown.Value = 'Crank-Nicolson';
            app.TimestepLabel = uilabel(app.UIFigure);
            app.TimestepLabel.Text = 'Timestep:';
            app.NumberofspacialstepsLabel = uilabel(app.UIFigure);
            app.NumberofspacialstepsLabel.Tooltip = {''};
            app.NumberofspacialstepsLabel.Text = {'Number of'; 'spacial steps:'};
            app.StepsizeLabel = uilabel(app.UIFigure);
            app.StepsizeLabel.Text = 'Step size:';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @KeyPress, true);
            app.Image.ImageSource = 'Rocket.png';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button.Tag = '1';
            app.Button.Tooltip = {'Temp502'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_2.Tag = '2';
            app.Button_2.Tooltip = {'Temp590'};
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_3.Tag = '3';
            app.Button_3.Tooltip = {'Temp597'};
            app.Button_3.Text = '';
            app.Button_3.Value = true;
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_4.Tag = '4';
            app.Button_4.Tooltip = {'Temp468'};
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_5.Tag = '5';
            app.Button_5.Tooltip = {'Temp480'};
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_6.Tag = '6';
            app.Button_6.Tooltip = {'Temp850'};
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_7.Tag = '7';
            app.Button_7.Tooltip = {'Temp711'};
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.Button_8.Tag = '8';
            app.Button_8.Tooltip = {'Temp730'};
            app.Button_8.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'temp597.jpg');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Tooltip = {'Hover over a tile to see the corresponding temperature data.'; 'Clicking on the tile or pressing the arrow keys will select the temperature data for analysis.'};
            app.Label_2.Text = '🛈';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Temp502', 'Temp590', 'Temp597', 'Temp468', 'Temp480', 'Temp850', 'Temp711', 'Temp730'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @Deselect, true);
            app.DropDown.Value = 'Temp597';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TileTemp, true);
            app.CButton = uitogglebutton(app.ButtonGroup);
            app.CButton.Text = '°C';
            app.KButton = uitogglebutton(app.ButtonGroup);
            app.KButton.Text = 'K';
            app.KButton.Value = true;
            app.FButton_2 = uitogglebutton(app.ButtonGroup);
            app.FButton_2.Text = '°F';
            app.MaximuminsidetemperatureLabel = uilabel(app.UIFigure);
            app.MaximuminsidetemperatureLabel.Text = 'Maximum inside temperature:';
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = '___ K';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Tooltip = {'Chose a numerical analysis method using the drop down menu.'; 'Note: Dirichlet boundary condition cannot be used to find an optimal thickness.'};
            app.Label_4.Text = '🛈';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error:';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Tooltip = {'Estimated error due to step size'};
            app.Label_5.Text = '_____ %';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Tooltip = {'Note: Calculating the error is an intensive process and may take a while for a large number of timesteps.'};
            app.CheckBox.Text = '';
            app.ChangeMaterialPropertiesButton = uibutton(app.UIFigure, 'state');
            app.ChangeMaterialPropertiesButton.ValueChangedFcn = createCallbackFcn(app, @KeyPress, true);
            app.ChangeMaterialPropertiesButton.Text = 'Change Material Properties';
            app.MaterialpropertiesPanel = uipanel(app.UIFigure);
            app.MaterialpropertiesPanel.Title = 'Material properties';
            app.MaterialpropertiesPanel.Visible = 'off';
            app.SpecificHeatCapacityJkgKLabel = uilabel(app.MaterialpropertiesPanel);
            app.SpecificHeatCapacityJkgKLabel.Text = 'Specific Heat Capacity [J/kg/K]';
            app.SpecificHeatCapacityJkgKEditField = uieditfield(app.MaterialpropertiesPanel, 'numeric');
            app.SpecificHeatCapacityJkgKEditField.Value = 628;
            app.DensitykgmLabel = uilabel(app.MaterialpropertiesPanel);
            app.DensitykgmLabel.Text = 'Density [kg/m³]';
            app.DensitykgmEditField = uieditfield(app.MaterialpropertiesPanel, 'numeric');
            app.DensitykgmEditField.Value = 144;
            app.ThermalConductivityWmKLabel = uilabel(app.MaterialpropertiesPanel);
            app.ThermalConductivityWmKLabel.Text = 'Thermal Conductivity [W/m·K]';
            app.ThermalConductivityWmKEditField = uieditfield(app.MaterialpropertiesPanel, 'numeric');
            app.ThermalConductivityWmKEditField.Value = 0.0484;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RocketTileUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end